classdef archivosupemo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        RightPanel                   matlab.ui.container.Panel
        UITable                      matlab.ui.control.Table
        AgregarGlobuloButton         matlab.ui.control.Button
        LimpiarGlobulosButton        matlab.ui.control.Button
        AjustedeTamaosLabel          matlab.ui.control.Label
        SimulacindeMovimientoButton  matlab.ui.control.Button
        SimulacinDiagnsticodeMalariaLabel  matlab.ui.control.Label
        PlacaNegativaLabel           matlab.ui.control.Label
        SliderLargoNegativo          matlab.ui.control.Slider
        PlacaPositivaLabel           matlab.ui.control.Label
        SliderLargoPositivo          matlab.ui.control.Slider
        DistanciaLabel               matlab.ui.control.Label
        Distancia                    matlab.ui.control.Slider
        GlobulosLabel                matlab.ui.control.Label
        AgregarGlobuloButton_2       matlab.ui.control.Button
        graph                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        v = 0.5; % Description
        %L  A   L  A
        medidasRejilla = [3 .2; 3 .2] % Medidas de Largo y Ancho, empezando con la placa negativa.
        
        Qpos = [1 -1.5; -1 -1.5; 1 1.5; -1 1.5;-1 0; 1 0]; % Vectores de Posición de Carga Qn (-) Qp (+). Representan coodenadas cartecianas.
        
        % Parametros de la Rejilla
        x1
        x2
        y1
        y2
        
        % Definición del Campo en X y Y
        xn = .2;
        yn = .2;
        
        ke = 8.987e9; % Constante de la ley de Coulomb
        
        Q = [-10 10 -10 10 -10 10]; % Valor de las cargas, empezando con la placa negativa.
        
        contadorG = 0; % Contador de globulos rojos
        
        Globulos = []; % Matriz de Globulos rojos
        % Posx Posy Posz Eg Fg Infectado
        
        radioGlobulo = .15;
        radioCarga = .05;
        dGlobulos
        
    end
    
    methods (Access = private)
        
        function [x, y, z] = getCargas(app,Qvec,Q,xgrid,ygrid,zgrid)
            Ex = app.ke*Q.*(xgrid-Qvec(1))./((xgrid-Qvec(1)).^2+(ygrid-Qvec(2)).^2).^1.5;
            Ey = app.ke*Q.*(ygrid-Qvec(2))./((xgrid-Qvec(1)).^2+(ygrid-Qvec(2)).^2).^1.5;
            Ez = zgrid;
            
            x = Ex;
            y = Ey;
            z = Ez;
        end
        
        function vertices = getVertices(~,v,ancho,largo,shiftX)
            vertices = [v*ancho+shiftX -v*largo -v;  % Vertice 1
                -v*ancho+shiftX -v*largo -v;  % Vertice 2
                -v*ancho+shiftX v*largo -v;   % Vertice 3
                v*ancho+shiftX v*largo -v;   % Vertice 4
                v*ancho+shiftX -v*largo v;   % Vertice 5
                -v*ancho+shiftX -v*largo v;   % Vertice 6
                -v*ancho+shiftX v*largo v;    % Vertice 7
                v*ancho+shiftX v*largo v];   % Vertice 8
            
        end
        
        function updateGraph(app)
            cla(app.graph)
            app.AgregarGlobuloButton.Enable = 'on';
            
            
            app.UITable.Data = transpose(app.Globulos);
            
            valuate = @(x) (abs(x)+1);
            
            app.x1 = valuate(app.Distancia.Value)*-1;
            app.x2 = valuate(app.Distancia.Value);
            app.y1 = -3;
            app.y2 = 3;
            
            
            % MeshGrid
            [xgrid,ygrid] = meshgrid(app.x1:app.xn:app.x2,app.y1:app.yn:app.y2);
            zgrid = xgrid * 0;
            
            Ex = 0;
            Ey = 0;
            Ez = 0;
            
            % Obtenemos las Componentes del Campo Electrico
            for i = 1:6 % Se repite por el número de cargas
                [x, y, z] = app.getCargas(app.Qpos(i,:),app.Q(i),xgrid,ygrid,zgrid);
                Ex = Ex + x;
                Ey = Ey + y;
                Ez = Ez + z;
            end
            
            E = sqrt(Ex.^2 + Ey.^2 + Ez.^2);
            
            %componentes vectoriales
            i = Ex ./ E;
            j = Ey ./ E;
            k = Ez ./ E;
            
            
            caras = [1 2 6 5; %cara 1
                2 3 7 6; %cara 2
                3 4 8 7; %cara 3
                1 4 8 5; %cara 4
                5 6 7 8; %cara 5 (arriba)
                1 2 3 4];%cara 6 (abajo)


            hold(app.graph,'on');
            % Dibuja Elementos del Campo
            patch(app.graph,'Faces', caras, 'Vertices', app.getVertices(app.v,app.medidasRejilla(2,2),app.medidasRejilla(2,1),app.Qpos(2,1)), 'FaceColor','r');
            patch(app.graph,'Faces', caras, 'Vertices', app.getVertices(app.v,app.medidasRejilla(1,2),app.medidasRejilla(1,1),app.Qpos(1,1)), 'FaceColor','b');
            quiver3(app.graph,xgrid,ygrid,zgrid,i,j,k);
            % Ajuste de Tamaño
            app.graph.XLim = [app.x1, app.x2];
            app.graph.YLim = [-3 3];
            app.graph.ZLim = app.graph.XLim;


        end
        
        function  updateGlobulos(app)
            %dGlobulos
            for i = 1:app.contadorG
                Posx = transpose(app.Globulos(i,1));
                Posy = transpose(app.Globulos(i,2));
                Posz = transpose(app.Globulos(i,3));
                d = transpose(app.Globulos(i,end));
                
                
                hold(app.graph,'on')
                % Graficar el globulo
                
                [x,y,z] = sphere(10);
                x = x * app.radioGlobulo;
                y = y * app.radioGlobulo;
                z = z * app.radioGlobulo;
                
                %Posx     %Posy   %Posz
                surf(app.graph,x+ Posx ,y+ Posy,z + Posz, 'Facecolor','r','EdgeColor','r','FaceAlpha',0.25);
                
                % Graficar cargas del globulo
                
                
                [x,y,z] = sphere(10);
                x = x * app.radioCarga;
                y = y * app.radioCarga;
                z = z * app.radioCarga;
                %Posx     %Posy   %Posz
                surf(app.graph,x - d + Posx ,y+ Posy,z + Posz, 'Facecolor','k','EdgeColor','k');
                surf(app.graph,x + d + Posx ,y+ Posy,z + Posz, 'Facecolor','k','EdgeColor','k');
                
                hold(app.graph,'off')
                
            end


        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.updateGraph;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {772, 772};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changing function: SliderLargoNegativo
        function SliderLargoNegativoValueChanged(app, event)
            app.medidasRejilla(1,1) = event.Value;
            
            
            app.Qpos(1,2) = event.Value/2;
            
            
            app.Qpos(3,2) = ((event.Value)*-1)/2;


            app.updateGraph;
        end

        % Value changing function: SliderLargoPositivo
        function SliderLargoPositivoValueChanging(app, event)
            
            app.medidasRejilla(2,1) = event.Value;
            
            
            app.Qpos(2,2) = event.Value/2;
            
            
            app.Qpos(4,2) = ((event.Value)*-1)/2;
            
            
            app.updateGraph;
            
        end

        % Value changing function: Distancia
        function DistanciaValueChanging(app, event)
            app.Qpos(1,1) = event.Value;
            app.Qpos(2,1) = (event.Value)*-1;
            
            app.Qpos(3,1) = event.Value;
            app.Qpos(4,1) = (event.Value)*-1;
            
            app.Qpos(5,1) = event.Value;
            app.Qpos(6,1) = (event.Value)*-1;
            
            app.Distancia.Value = event.Value;
            app.updateGraph;
            
        end

        % Button pushed function: AgregarGlobuloButton
        function AgregarGlobuloButtonPushed(app, event)
            % Distancia Aleatoria
            app.dGlobulos = (rand()* app.radioGlobulo);
            % Contador
            app.contadorG = app.contadorG + 1;
            
            % Posición del globulo
            VarFloat = (randi([-100,100],1,1))/100;
            Posx = (randi([-1.0,1.0],1,1)) * VarFloat;
            
            VarFloat = (randi([-100,100],1,1))/100;
            Posy = (randi([-1.0,1.0],1,1)) * VarFloat;
            
            Posz = 0;
            
            
            %Calcular fuerza y campo electrico
            cargasC = [-2e-6 2e-6];
            
            E1 = app.ke * (cargasC(1)/(app.dGlobulos*2)^2);
            E2 = app.ke * (cargasC(2)/(app.dGlobulos*2)^2);
            Eg = E2 - E1;
            Qneta = 2e-6;
            Fg = Eg *Qneta;
            Infectado = 0;
            
            if Eg <= 1.25e+6
                Infectado = 1;
            end
            
            
            app.Globulos = [app.Globulos;Posx Posy Posz Eg Fg Infectado app.dGlobulos];
            app.UITable.ColumnName = [app.UITable.ColumnName; strcat("Globulo ",int2str(app.contadorG))];
            
            
            app.UITable.Data = transpose(app.Globulos(:,1:end-1));


            app.updateGlobulos;


        end

        % Button pushed function: SimulacindeMovimientoButton
        function SimulacindeMovimientoButtonPushed(app, event)
            % Store original button text
            originalButtonText = app.SimulacindeMovimientoButton.Text;
            % When the function ends, return the original button state
            cleanup = onCleanup(@()set(app.SimulacindeMovimientoButton,'Text',originalButtonText,'Icon',''));
            % Change button name to "Processing"
            app.SimulacindeMovimientoButton.Text = 'Procesando...';
            % Put text on top of icon
            app.SimulacindeMovimientoButton.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.SimulacindeMovimientoButton.BackgroundColor,15,1,200),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            app.SimulacindeMovimientoButton.Icon = wbar;
            
            
            for i = 1:app.contadorG
                if(transpose(app.Globulos(i,end-1)) == 1 && transpose(app.Globulos(i,1)) ~= 1)
                    anim = linspace(transpose(app.Globulos(i,1)),app.Qpos(1,1),10);
                    for j = anim
                        app.Globulos(i,1)=j;
                        app.updateGraph;
                        app.updateGlobulos;
                        
                        % Update progress bar
                        currentProg = min(round((size(wbar,2)-2)*(i/app.contadorG)),size(wbar,2)-2);
                        app.SimulacindeMovimientoButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
                        app.SimulacindeMovimientoButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                        app.SimulacindeMovimientoButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
                        pause(.1);
                        
                    end


                end
                
            end
        end

        % Button pushed function: LimpiarGlobulosButton
        function LimpiarGlobulosButtonPushed(app, event)
            app.Globulos = [];
            app.UITable.Data = [];
            app.UITable.ColumnName = ['Prueba'];
            app.contadorG = 0;
            app.updateGraph;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Prueba'};
            app.UITable.RowName = {'Pos x'; 'Pos y'; 'Pos z'; 'Eg'; 'Fuerza '; 'Infectado'};
            app.AgregarGlobuloButton = uibutton(app.RightPanel, 'push');
            app.AgregarGlobuloButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarGlobuloButtonPushed, true);
            app.AgregarGlobuloButton.Text = 'Agregar Globulo';
            app.LimpiarGlobulosButton = uibutton(app.RightPanel, 'push');
            app.LimpiarGlobulosButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarGlobulosButtonPushed, true);
            app.LimpiarGlobulosButton.Text = 'Limpiar Globulos';
            app.AjustedeTamaosLabel = uilabel(app.RightPanel);
            app.AjustedeTamaosLabel.Text = 'Ajuste de Tamaños';
            app.SimulacindeMovimientoButton = uibutton(app.RightPanel, 'push');
            app.SimulacindeMovimientoButton.ButtonPushedFcn = createCallbackFcn(app, @SimulacindeMovimientoButtonPushed, true);
            app.SimulacindeMovimientoButton.Text = 'Simulación de Movimiento';
            app.SimulacinDiagnsticodeMalariaLabel = uilabel(app.RightPanel);
            app.SimulacinDiagnsticodeMalariaLabel.Text = 'Simulación: Diagnóstico de Malaria';
            app.PlacaNegativaLabel = uilabel(app.RightPanel);
            app.PlacaNegativaLabel.Text = 'Placa Negativa';
            app.SliderLargoNegativo = uislider(app.RightPanel);
            app.SliderLargoNegativo.Limits = [1 5];
            app.SliderLargoNegativo.ValueChangingFcn = createCallbackFcn(app, @SliderLargoNegativoValueChanged, true);
            app.SliderLargoNegativo.Value = 3;
            app.PlacaPositivaLabel = uilabel(app.RightPanel);
            app.PlacaPositivaLabel.Text = 'Placa Positiva';
            app.SliderLargoPositivo = uislider(app.RightPanel);
            app.SliderLargoPositivo.Limits = [1 5];
            app.SliderLargoPositivo.ValueChangingFcn = createCallbackFcn(app, @SliderLargoPositivoValueChanging, true);
            app.SliderLargoPositivo.Value = 3;
            app.DistanciaLabel = uilabel(app.RightPanel);
            app.DistanciaLabel.Text = 'Distancia';
            app.Distancia = uislider(app.RightPanel);
            app.Distancia.Limits = [-10 10];
            app.Distancia.ValueChangingFcn = createCallbackFcn(app, @DistanciaValueChanging, true);
            app.Distancia.Value = 2;
            app.GlobulosLabel = uilabel(app.RightPanel);
            app.GlobulosLabel.Text = 'Globulos';
            app.AgregarGlobuloButton_2 = uibutton(app.RightPanel, 'push');
            app.AgregarGlobuloButton_2.Text = 'Agregar Globulo';
            app.graph = uiaxes(app.RightPanel);
            xlabel(app.graph, 'X')
            ylabel(app.graph, 'Y')
            zlabel(app.graph, 'Z')
            app.graph.XLim = [-6 6];
            app.graph.YLim = [-6 6];
            app.graph.ZLim = [-6 6];
            app.graph.Tag = 'graph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = archivosupemo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end